classdef addRoi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddcellmarksUIFigure           matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool3                      matlab.ui.container.toolbar.PushTool
        PushTool                       matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        GridLayout                     matlab.ui.container.GridLayout
        EditcelltypesButton            matlab.ui.control.Button
        DeleteselectedButton           matlab.ui.control.Button
        SelectAllexistingROIsCheckBox  matlab.ui.control.CheckBox
        ExistingROIsLabel              matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        HeightSpinner                  matlab.ui.control.Spinner
        HeightSpinnerLabel             matlab.ui.control.Label
        WidthSpinner                   matlab.ui.control.Spinner
        WidthSpinnerLabel              matlab.ui.control.Label
        MarksizeLabel                  matlab.ui.control.Label
        CelltypeButtonGroup            matlab.ui.container.ButtonGroup
        Category8Button                matlab.ui.control.ToggleButton
        Category7Button                matlab.ui.control.ToggleButton
        Category6Button                matlab.ui.control.ToggleButton
        Category5Button                matlab.ui.control.ToggleButton
        Category4Button                matlab.ui.control.ToggleButton
        Category3Button                matlab.ui.control.ToggleButton
        Category2Button                matlab.ui.control.ToggleButton
        Category1Button                matlab.ui.control.ToggleButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        id = 1;
        imgHandle;
        rois = dictionary;% Description
        categories_ids = ["Category 1", "Category 2","Category 3","Category 4","Category 5","Category 6","Category 7","Category 8"]; % Thisis uids for cateogries
        categories_colors = ["r", "g", "b", "c", "m", "y", "k", "w"]; % hardcoded colors - not planning to change
        categories_names = ["Category 1", "Category 2","Category 3","Category 4","Category 5","Category 6","Category 7","Category 8"];
        categories = dictionary; 
        categories_names_dict = dictionary;
        ids = [];
        currentRoi;
        img;
        filePath;
        moved;
        already_deleted;
        load_handle;
        shit;
    end
    
    properties (Access = public)
        new_categories; % Description
    end
    
    methods (Access = private)

        function parseRoiArrayToDict(app, arr)
            for i = 1:length(arr)
                if length(arr) == 1
                    temp = arr;
                else
                    temp = arr(i);
                end
                w = double(temp.width);
                h = double(temp.height);
                x = double(temp.xStart);
                y = double(temp.yStart);
                newRoi = drawrectangle(app.UIAxes, 'Position', [x,y,w,h], 'Color', app.categories(temp.cellType), 'Label', num2str(temp.id));
                app.rois(temp.id) = roi(temp.id, temp.name, temp.cellType, x, y, w, h, newRoi);
                app.UITable.Data = [app.UITable.Data; {temp.id, temp.name, char(app.categories_names_dict(temp.cellType)), false}];
                addlistener(newRoi,'MovingROI',@app.allevents);
                addlistener(newRoi,'ROIMoved',@app.allevents);
                addlistener(newRoi, 'ObjectBeingDestroyed', @app.deleteRoi);
                app.ids(end+1) = temp.id;
            end
            app.currentRoi = -1;
            app.id = app.ids(end)+1;
        end
        
        function addNewRoi(app, x, y, w, h, newRoi)
            app.ids(end+1) = app.id;
            app.rois(app.id) = roi(app.id, '', app.CelltypeButtonGroup.SelectedObject.Tag, x, y, w, h, newRoi);
            app.UITable.Data = [app.UITable.Data; {app.id, app.rois(app.id).name, char(app.categories_names_dict(app.rois(app.id).cellType)), false}];
            app.id = app.id+1;
            addlistener(newRoi,'MovingROI',@app.allevents);
            addlistener(newRoi,'ROIMoved',@app.allevents);
            addlistener(newRoi, 'ObjectBeingDestroyed', @app.deleteRoi);
        end
        
        function selectButton(app, selector)
            switch selector
                case app.Category1Button.Text
                    app.CelltypeButtonGroup.SelectedObject = app.Category1Button;
                case app.Category2Button.Text
                    app.CelltypeButtonGroup.SelectedObject = app.Category2Button;
                case app.Category3Button.Text
                    app.CelltypeButtonGroup.SelectedObject = app.Category3Button;
                case app.Category4Button.Text
                    app.CelltypeButtonGroup.SelectedObject = app.Category4Button;
                case app.Category5Button.Text
                    app.CelltypeButtonGroup.SelectedObject = app.Category5Button;
                case app.Category6Button.Text
                    app.CelltypeButtonGroup.SelectedObject = app.Category6Button;
                case app.Category7Button.Text
                    app.CelltypeButtonGroup.SelectedObject = app.Category7Button;
                case app.Category8Button.Text
                    app.CelltypeButtonGroup.SelectedObject = app.Category8Button;
            end
            app.CelltypeButtonGroup.Title = "Cell type: "+app.CelltypeButtonGroup.SelectedObject.Text;
        end

        function res = checkIfSelected(app)
            res = false;
            [w, ~] = size(app.UITable.Data);
            for i = 1:w
                if app.UITable.Data{i, 4}
                    res = true;
                    return;
                end
            end
        end
        
        function changeRoisCategory(app, category)
            newData = [];
            [w, ~] = size(app.UITable.Data);
            for i = 1:w
                currentCategory = category;
                if ~app.UITable.Data{i, 4}
                    currentCategory = app.UITable.Data{i, 3};
                end
                if ~strcmp(app.categories_names_dict(app.rois(app.UITable.Data{i, 1}).cellType), currentCategory)
                    app.rois(app.UITable.Data{i, 1}).cellType = app.UITable.Data{i, 3};
                    c = app.rois(app.UITable.Data{i, 1});
                    c.objHandle.Color = app.categories(app.CelltypeButtonGroup.SelectedObject.Tag);
                end
                newData = [newData; {app.UITable.Data{i, 1}, app.UITable.Data{i, 2}, currentCategory, app.UITable.Data{i, 4}}];
            end
            app.UITable.Data = newData;
        end

        % Usefull listener for handling rois drawrectangle
        function allevents(app, src, evt)
            evname = evt.EventName;
            evId = str2double(src.Label);
            temp = app.rois(evId);
            switch(evname)
                case{'ROIMoved'}
                    %to ma znaczenie -- jak się puści dopiero to się
                    %wywołuje (takie changed
%                     app.currentRoi = evId;
                    app.selectRowUITable(evId);
                    app.UITableSelectionChanged();
                    app.setNewRectangle(evt.CurrentPosition);
                    disp(['ROI moved previous position: ' mat2str(evt.PreviousPosition)]);
                    disp(['ROI moved current position: ' mat2str(evt.CurrentPosition)]);

%                 case{'MovingROI'}
                %to olać
%                     disp(['ROI moving previous position: ' mat2str(evt.PreviousPosition)]);
%                     disp(['ROI moving current position: ' mat2str(evt.CurrentPosition)]);
            end
        end

        function selectRowUITable(app, evId)
            [w, ~] = size(app.UITable.Data);
            for i = 1:w
                if app.UITable.Data{i, 1} == evId
                    app.UITable.Selection = i;
                    break;
                end
            end
        end

        function setNewRectangle(app, positions)
            app.rois(app.currentRoi).xStart = positions(1);
            app.rois(app.currentRoi).yStart = positions(2);
            app.WidthSpinner.Value = round(positions(3));
            app.HeightSpinner.Value = round(positions(4));
            app.moved = true;
            app.WidthSpinnerValueChanged();
        end
        
        
        function deleteRoi(app, src, evt)
            if app.already_deleted
                app.already_deleted = false;
                return;
            end
            evname = evt.EventName;
            if ~strcmp(evname, 'ObjectBeingDestroyed')
                disp("test");
                return;
            end
            evId = str2double(src.Label);
            temp = app.rois(evId);
            
            selection = uiconfirm(app.AddcellmarksUIFigure, 'Are you sure you want to delete element?','Delete ROI',...
                'Options',{'Delete','Cancel'}, 'DefaultOption', 1,'CancelOption', 2, 'Icon','error');
            if selection~="Delete"
                %  already deleted rectangle, but not object -> cancelled
                %  so draw it back
                selectRowUITable(app, evId);
                newRoir = drawrectangle(app.UIAxes, 'Position', [app.rois(evId).xStart,app.rois(evId).yStart,app.rois(evId).width,app.rois(evId).height], 'Color', app.categories(app.rois(evId).cellType), 'Label', num2str(evId));
                app.rois(evId).objHandle = newRoir;
                addlistener(newRoir,'ObjectBeingDestroyed', @app.deleteRoi);
                addlistener(newRoir,'MovingROI',@app.allevents);
                addlistener(newRoir,'ROIMoved',@app.allevents);
                return;
            end
            newData = [];
            [w, ~] = size(app.UITable.Data);
            for i = 1:w
                if evId ~= app.UITable.Data{i, 1}
                    newData = [newData; {app.UITable.Data{i, 1}, app.UITable.Data{i, 2}, app.UITable.Data{i, 3}, app.UITable.Data{i, 4}}];
                end
            end
            app.rois(evId) = [];
            app.ids(app.ids==evId) = [];
            app.UITable.Data = newData;

            if isempty(app.UITable.Data)
                app.id = 1;
            end
            app.currentRoi = -1;
        end
        
        function autoDetect(app, previous)
            [~, ~, ~, mask] = nuclei_counter(app.img(:,:,1));
            labeldMask = bwlabeln(mask);
            nOfCells = max(max(labeldMask));
            for i = 1:nOfCells
                currentCell = labeldMask ==i;
                [x,y,w,h] = app.calculatePositions(currentCell);
                newRoir = drawrectangle(app.UIAxes, 'Position', [x,y,w,h], 'Color', app.categories(app.CelltypeButtonGroup.SelectedObject.Tag), 'Label', num2str(app.id));
                app.addNewRoi(x, y, w, h, newRoir);
            end
        end

        function [x,y,w,h] = calculatePositions(app, mask)
            temp = regionprops(mask, "BoundingBox");
            temp = temp.BoundingBox;
            x = temp(1);
            y = temp(2);
            w = temp(3);
            h = temp(4);
        end
        
        function [mask, cats] = generateMask(app)
            mask = zeros(size(app.img));
            cats = strings(size(app.img));
            cats = cats(:, :, 1);
            keys_arr = keys(app.rois);
            for i = 1:numel(keys_arr)
                temp = app.rois(keys_arr(i));
                xStart = round(temp.xStart);
                xEnd = round(temp.xStart+temp.width);
                yStart = round(temp.yStart);
                yEnd = round(temp.yStart+temp.height);
                disp(temp);
                disp(size(app.img));
                temp_img = app.img(yStart:yEnd, xStart:xEnd, 1);

                [~, ~, ~, part_mask] = nuclei_counter(temp_img);
                if sum(sum(part_mask)) == 0
                    part_mask(:,:) = 1;
                end

                temp_cats = cats(yStart:yEnd, xStart:xEnd);
                indx = part_mask(:,:,1) > 0;
                temp_cats(indx) = app.categories_names_dict(temp.cellType);

                cats(yStart:yEnd, xStart:xEnd) = temp_cats;
                mask(yStart:yEnd, xStart:xEnd, 1) = part_mask;
            end
            mask(:,:,2) = mask(:,:,1);
            mask(:,:,3) = mask(:,:,1);
        end
        
        function assignCategories(app, saved_categories)
            app.Category1Button.BackgroundColor = app.categories_colors(1);
            app.Category2Button.BackgroundColor = app.categories_colors(2);
            app.Category3Button.BackgroundColor = app.categories_colors(3);
            app.Category3Button.FontColor = "white";
            app.Category4Button.BackgroundColor = app.categories_colors(4);
            app.Category5Button.BackgroundColor = app.categories_colors(5);
            app.Category6Button.BackgroundColor = app.categories_colors(6);
            app.Category7Button.BackgroundColor = app.categories_colors(7);
            app.Category7Button.FontColor = "white";
            app.Category8Button.BackgroundColor = app.categories_colors(8);

            % restore saved categories names
            if isempty(saved_categories)
                return;
            end
            temp_arr = strings(1,length(app.categories_ids));
            for i = 1:length(temp_arr)
%                 disp(saved_categories{i});
                temp_arr(i) = saved_categories{i,1};
            end
            app.categories_names = temp_arr;
            app.Category1Button.Text = app.categories_names(1);
            app.Category2Button.Text = app.categories_names(2);
            app.Category3Button.Text = app.categories_names(3);
            app.Category4Button.Text = app.categories_names(4);
            app.Category5Button.Text = app.categories_names(5);
            app.Category6Button.Text = app.categories_names(6);
            app.Category7Button.Text = app.categories_names(7);
            app.Category8Button.Text = app.categories_names(8);
        end
end
    
    methods (Access = public)
        
        function reload_categories(app, new_categories)
            % restore saved categories names
            app.categories_names = new_categories;
            app.Category1Button.Text = app.categories_names(1);
            app.Category2Button.Text = app.categories_names(2);
            app.Category3Button.Text = app.categories_names(3);
            app.Category4Button.Text = app.categories_names(4);
            app.Category5Button.Text = app.categories_names(5);
            app.Category6Button.Text = app.categories_names(6);
            app.Category7Button.Text = app.categories_names(7);
            app.Category8Button.Text = app.categories_names(8);


            app.categories_names_dict = dictionary(app.categories_ids, app.categories_names); % there are names

            app.CelltypeButtonGroup.Title = "Cell type: "+app.CelltypeButtonGroup.SelectedObject.Text;

            % Editting UITable
            newData = [];
            [w, ~] = size(app.UITable.Data);
            for i = 1:w
                tmp = app.rois(app.UITable.Data{i, 1});
                newData = [newData; {app.UITable.Data{i, 1}, app.UITable.Data{i, 2}, char(app.categories_names_dict(tmp.cellType)), app.UITable.Data{i, 4}}];
            end
            app.UITable.Data = newData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, autoDetect, filePath, prev_rois, saved_categories)
            image(app.UIAxes, img, 'HitTest', 'off');
            [y, x, ~] = size(img);
            app.UIAxes.XLim = [1,x];
            app.UIAxes.YLim = [1,y];
            app.img = img;
            app.categories = dictionary(app.categories_ids, app.categories_colors); % there are colors :)
            app.UIAxes.Visible = "on";
            app.moved = false;
            app.already_deleted = false;
            app.assignCategories(saved_categories); % may overwrite categories_names, stay here like next line
            app.categories_names_dict = dictionary(app.categories_ids, app.categories_names); % there are names
            app.shit = saved_categories;
            app.CelltypeButtonGroup.Title = "Cell type: "+app.CelltypeButtonGroup.SelectedObject.Text;
            app.filePath = filePath;
            app.rois = dictionary;
            app.id = 1;
            app.currentRoi = -1;
            if(autoDetect)
                app.autoDetect(prev_rois);
                return;
            elseif ~isempty(prev_rois)
                app.parseRoiArrayToDict(prev_rois);
            end
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            %save and go to basic view
            % open file in write mode
            filter = {'*.json'};
            [file, path] = uiputfile(filter);
            fullPath = strcat(path,file);
            if isempty(fullPath)
                return;
            end
            % convert image to base64
            %

            fhand = fopen(fullPath,'w');
            fprintf(fhand, '{\n');
%             t = struct("img", app.filePath);
            app.filePath = strrep(app.filePath, '\', '/');
            fprintf(fhand, sprintf('"img": "%s"', app.filePath));
            fprintf(fhand, ',\n"rois": [\n');
            keys_arr = keys(app.rois);
            for i = 1:numel(keys_arr)
                temp = app.rois(keys_arr(i));
                temp.objHandle = "";
                fprintf(fhand, jsonencode(temp));
                if i < numel(keys_arr)
                    fprintf(fhand, ',\n');
                else
                    fprintf(fhand, '\n');
                end
            end
            fprintf(fhand, '],\n');
            % dopisać zapis
            fprintf(fhand, sprintf('"categories": [\n'));
            keys_arr = keys(app.categories_names_dict);
            for i = 1:numel(keys_arr)
                val = app.categories_names_dict(keys_arr(i));
                fprintf(fhand, sprintf('"%s"', val));
                if i < numel(keys_arr)
                    fprintf(fhand, ',\n');
                else
                    fprintf(fhand, '\n');
                end
            end
            fprintf(fhand, ']}');
            fclose(fhand);
            disp("Finished");
        end

        % Button down function: UIAxes
        function ImageClicked2(app, event)
            app.currentRoi = -1;
            w = double(app.WidthSpinner.Value);
            h = double(app.HeightSpinner.Value);
            x = event.IntersectionPoint(1)-w/2;
            y = event.IntersectionPoint(2)-h/2;
            [y_max, x_max, ~] = size(app.img)
            if x <= 0
                x = 1;
            end
            if y <= 0
                y = 1;
            end
            if x + w > x_max
                w = x_max-x;
                app.WidthSpinner.Value = round(w);
            end
            if y + h > y_max
                h = y_max-y;
                app.HeightSpinner.Value = round(h);
            end
            newRoir = drawrectangle(app.UIAxes, 'Position', [x,y,w,h], 'Color', app.categories(app.CelltypeButtonGroup.SelectedObject.Tag), 'Label', num2str(app.id));
            app.addNewRoi(x, y, w, h, newRoir);
        end

        % Selection changed function: CelltypeButtonGroup
        function CelltypeButtonGroupSelectionChanged(app, event)
            if ~checkIfSelected(app)
                app.selectButton(event.NewValue.Text);
                return;
            end
            selection = uiconfirm(app.AddcellmarksUIFigure, 'This operation will change category of every selected roi, Are you sure you want to continue?','Change categories',...
                'Options',{'Continue','Cancel'}, 'DefaultOption', 1,'CancelOption', 2, 'Icon','warning');
            disp(selection);
            if selection~="Continue"
                app.selectButton(event.OldValue.Text);
                return;
            end
            disp(event);
            app.selectButton(event.NewValue.Text);
            app.changeRoisCategory(event.NewValue.Text);
            app.SelectAllexistingROIsCheckBox.Value = 0;
            app.SelectAllexistingROIsCheckBoxValueChanged();
            app.currentRoi = -1;
        end

        % Value changed function: SelectAllexistingROIsCheckBox
        function SelectAllexistingROIsCheckBoxValueChanged(app, event)
            value = app.SelectAllexistingROIsCheckBox.Value;
            newData = [];
            [w, ~] = size(app.UITable.Data);
            for i = 1:w
                disp(i);
                newData = [newData; {app.UITable.Data{i, 1}, app.UITable.Data{i, 2}, app.UITable.Data{i, 3}, value}];
            end
            app.UITable.Data = newData;
            app.currentRoi = -1;
        end

        % Button pushed function: DeleteselectedButton
        function DeleteselectedButtonPushed(app, event)
            selection = uiconfirm(app.AddcellmarksUIFigure, 'Are you sure you want to delete all selected elements?','Delete all selected rois',...
                'Options',{'Delete','Cancel'}, 'DefaultOption', 1,'CancelOption', 2, 'Icon','error');
            disp(selection);
            if selection~="Delete"
                return;
            end
            newData = [];
            [w, ~] = size(app.UITable.Data);
            for i = 1:w
                if ~app.UITable.Data{i, 4}
                    newData = [newData; {app.UITable.Data{i, 1}, app.UITable.Data{i, 2}, app.UITable.Data{i, 3}, app.UITable.Data{i, 4}}];
                else
                    app.already_deleted = true;
                    delete(app.rois(app.UITable.Data{i, 1}).objHandle)
                    app.rois(app.UITable.Data{i, 1}) = [];
                    app.ids(app.ids==app.UITable.Data{i, 1}) = [];
                end
            end
            app.UITable.Data = newData;
            if isempty(app.UITable.Data)
                app.id = 1;
            end
            app.SelectAllexistingROIsCheckBox.Value = 0;
            app.currentRoi = -1;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            [w, ~] = size(app.UITable.Data);
            for i = 1:w
                if ~strcmp(app.rois(app.UITable.Data{i, 1}).name, newDisplayData{i, 2})
                    app.rois(app.UITable.Data{i, 1}).name = app.UITable.Data{i, 2};
                end
            end
            app.UITable.Data = newDisplayData;
            app.currentRoi = -1;
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            try
                selectedId = app.UITable.Data{selection, 1};
                app.currentRoi = selectedId;
            catch
                app.currentRoi = -1;
                return;
            end
            selectedRoi = app.rois(selectedId);
            app.WidthSpinner.Value = double(selectedRoi.width);
            app.HeightSpinner.Value = double(selectedRoi.height);
            app.selectButton(selectedRoi.cellType);
        end

        % Value changed function: HeightSpinner, WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            if app.currentRoi < 0
                return;
            end
            app.already_deleted = true;
            delete(app.rois(app.currentRoi).objHandle);
            c = app.rois(app.currentRoi);

            w = double(app.WidthSpinner.Value);
            h = double(app.HeightSpinner.Value);
            if app.moved 
                x = c.xStart;
                y = c.yStart;
            else
                x = (c.xStart+c.width/2)-w/2;
                y = (c.yStart+c.height/2)-h/2;
            end
            app.moved = false;
            newRoir = drawrectangle(app.UIAxes, 'Position', [x,y,w,h], 'Color', app.categories(app.CelltypeButtonGroup.SelectedObject.Tag), 'Label', num2str(c.id));
            app.rois(app.currentRoi) = roi(c.id, c.name, c.cellType, x, y, w, h, newRoir);
            addlistener(newRoir,'MovingROI',@app.allevents);
            addlistener(newRoir,'ROIMoved',@app.allevents);
            addlistener(newRoir, 'ObjectBeingDestroyed', @app.deleteRoi);
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            selection = uiconfirm(app.AddcellmarksUIFigure, 'Do you want to save mask it as mask','Save mask',...
                'Options',{'Yes','No'}, 'DefaultOption', 1,'CancelOption', 2, 'Icon','info');
            if selection~="Yes"
                return;
            end
            filter = {'*.png;*.jpg;*.jpeg'};
            [file, path] = uiputfile(filter);
            fullPath = strcat(path,file);

            [mask, cats] = app.generateMask();
            imwrite(mask, fullPath);
            extStart = find(file == '.');
            matFileName = file(1:extStart-1);
            save(strcat(path,matFileName,".mat"), 'cats');

        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            blank_template();
        end

        % Button pushed function: EditcelltypesButton
        function EditcelltypesButtonPushed(app, event)
            edit_categories(app, app.categories_names);
%             delete(app);
        end

        % Size changed function: AddcellmarksUIFigure
        function AddcellmarksUIFigureSizeChanged(app, event)
            position = app.AddcellmarksUIFigure.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AddcellmarksUIFigure = uifigure('Visible', 'off');
            app.AddcellmarksUIFigure.Name = 'Add cell marks';
            app.AddcellmarksUIFigure.SizeChangedFcn = createCallbackFcn(app, @AddcellmarksUIFigureSizeChanged, true);
            app.Toolbar = uitoolbar(app.AddcellmarksUIFigure);
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'add_icon.svg');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'save.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'export_icon.svg');
            app.GridLayout = uigridlayout(app.AddcellmarksUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ImageClicked2, true);
            app.UIAxes.Visible = 'off';
            app.CelltypeButtonGroup = uibuttongroup(app.GridLayout);
            app.CelltypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CelltypeButtonGroupSelectionChanged, true);
            app.CelltypeButtonGroup.Title = 'Cell type';
            app.Category1Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category1Button.Tag = 'Category 1';
            app.Category1Button.Text = 'Category 1';
            app.Category1Button.Value = true;
            app.Category2Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category2Button.Tag = 'Category 2';
            app.Category2Button.Text = 'Category 2';
            app.Category3Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category3Button.Tag = 'Category 3';
            app.Category3Button.Text = 'Category 3';
            app.Category4Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category4Button.Tag = 'Category 4';
            app.Category4Button.Text = 'Category 4';
            app.Category5Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category5Button.Tag = 'Category 5';
            app.Category5Button.Text = 'Category 5';
            app.Category6Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category6Button.Tag = 'Category 6';
            app.Category6Button.Text = 'Category 6';
            app.Category7Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category7Button.Tag = 'Category 7';
            app.Category7Button.Text = 'Category 7';
            app.Category8Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category8Button.Tag = 'Category 8';
            app.Category8Button.Text = 'Category 8';
            app.MarksizeLabel = uilabel(app.GridLayout);
            app.MarksizeLabel.Text = 'Mark size';
            app.WidthSpinnerLabel = uilabel(app.GridLayout);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.GridLayout);
            app.WidthSpinner.Limits = [0 Inf];
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.HeightSpinnerLabel = uilabel(app.GridLayout);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout);
            app.HeightSpinner.Limits = [0 Inf];
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Id'; 'Description'; 'Category'; 'Select'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false true false true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.ExistingROIsLabel = uilabel(app.GridLayout);
            app.ExistingROIsLabel.Text = 'Existing ROIs';
            app.SelectAllexistingROIsCheckBox = uicheckbox(app.GridLayout);
            app.SelectAllexistingROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllexistingROIsCheckBoxValueChanged, true);
            app.SelectAllexistingROIsCheckBox.Text = 'Select All existing ROIs';
            app.DeleteselectedButton = uibutton(app.GridLayout, 'push');
            app.DeleteselectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedButtonPushed, true);
            app.DeleteselectedButton.Text = 'Delete selected';
            app.EditcelltypesButton = uibutton(app.GridLayout, 'push');
            app.EditcelltypesButton.ButtonPushedFcn = createCallbackFcn(app, @EditcelltypesButtonPushed, true);
            app.EditcelltypesButton.Text = 'Edit cell types';
            % Show the figure after all components are created
            app.AddcellmarksUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addRoi(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddcellmarksUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddcellmarksUIFigure)
        end
    end
end